classdef arboles_de_desicion < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        ArbolesdedesicionLabel  matlab.ui.control.Label
        DatosPanel              matlab.ui.container.Panel
        UITable                 matlab.ui.control.Table
        CargardatosButton       matlab.ui.control.Button
        ArchivoEditFieldLabel   matlab.ui.control.Label
        ArchivoEditField        matlab.ui.control.EditField
        GraficacionPanel        matlab.ui.container.Panel
        UIAxes                  matlab.ui.control.UIAxes
        GraficarButton          matlab.ui.control.Button
    end

    
    methods (Access = public)
        
        function [arbol] = ID3(ejemplos, atributos, atributosActivos)
            
            % args:
            %       ejemplos            - matriz de 1s y 0s para verdaderos y falsos,
            %                             siendo el últmo de cada fila el valor del
            %                             atributo clasificador
            %       atributos           - arreglo de cadenas de atributos (no CLASS)
            %
            %       atributosActivos    - vector de 1s y 0s, 1 corresponde al
            %                             atributo activo(no CLASS)
            
            % return:
            %       arbol                - el nodo raíz de un árbol de decisión
            % estructura de arbol:
            %       valor                - será la cadena para el atributo de división,
            %                               'true' o 'false' para la hoja.
            %       izquierdo            - puntero izquierdo a otro nodo del árbol
            %                              (izquierdo significa que el atributo de división era falso)
            %       derecho               - puntero derecho a otro nodo del árbol
            %                              (derecho significa que el atributo de división era verdadero)
            
            if (isempty(ejemplos))
                error ('Debe proveer ejemplos');
            end
            
            %constantes
            numeroAtributos = length(atributosActivos)
            numeroEjemplos = length(ejemplos(:,1));
            
            % Creamos el nodo arbol
            arbol = struct('valor', 'null', 'izquierdo', 'null', 'derecho', 'null');
            
            % Si el último valor de todas las filas en los ejemplos es 1, devuelva el árbol etiquetado 'verdadero'
            
            sumUltimaCol = sum(ejemplos(:, numeroAtributos + 1));
            
            if (sumUltimaCol == numeroEjemplos)
                arbol.valor = 'true';
                return
            end
            
            % Si el último valor de todas las filas en los ejemplos es 0, devuelva el árbol etiquetado 'Falso'
            if (sumUltimaCol == 0)
                arbol.valor = 'false';
                return
            end
            
            % Si atributosActivos está vacío, devuelve el árbol con la etiqueta con el valor más común
            if (sum(atributosActivos) == 0)
                if (sumUltimaCol >= numeroEjemplos / 2)
                    arbol.valor = 'true';
                else
                    arbol.valor = 'false';
                end
                return
            end
            
            % Encontramos la entropia actual
            p1 = sumUltimaCol / numeroEjemplos;
            if (p1 == 0);
                p1_eq = 0;
            else
                p1_eq = -1*p1*log2(p1);
            end
            p0 = (numeroEjemplos - sumUltimaCol) / numeroEjemplos;
            if (p0 == 0);
                p0_eq = 0;
            else
                p0_eq = -1*p0*log2(p0);
            end
            entropiaActual = p1_eq + p0_eq;
            
            % Encuentra el atributo que maximiza la ganancia de la información.
            
            ganancia = -1*ones(1,numeroAtributos); %-1 si está inactivo, ganancias para todos los demás bucles
            %a través de los atributos que actualizan las ganancias, asegurándose de que todavía estén activos
            
            for i=1:numeroAtributos
                if (atributosActivos(i)) % Este sigue activo, actualiza su ganancia.
                    s0 = 0; s0_and_true = 0;
                    s1 = 0; s1_and_true = 0;
                    for j=1:numeroEjemplos
                        if (ejemplos(j,i)) % Esta instancia ha dividido el Atr. true
                            s1 = s1 + 1;
                            if (ejemplos(j, numeroAtributos + 1)) %atr. objetivo es true
                                s1_and_true = s1_and_true + 1;
                            end
                        else
                            s0 = s0 + 1;
                            if (ejemplos(j, numeroAtributos + 1)) %atr. objeivo es true
                                s0_and_true = s0_and_true + 1;
                            end
                        end
                    end
                    
                    % Entropia para S(v=1)
                    if (~s1)
                        p1 = 0;
                    else
                        p1 = (s1_and_true / s1);
                    end
                    if (p1 == 0)
                        p1_eq = 0;
                    else
                        p1_eq = -1*(p1)*log2(p1);
                    end
                    if (~s1)
                        p0 = 0;
                    else
                        p0 = ((s1 - s1_and_true) / s1);
                    end
                    if (p0 == 0)
                        p0_eq = 0;
                    else
                        p0_eq = -1*(p0)*log2(p0);
                    end
                    entropy_s1 = p1_eq + p0_eq;
                    
                    % Entropia para S(v=0)
                    if (~s0)
                        p1 = 0;
                    else
                        p1 = (s0_and_true / s0);
                    end
                    if (p1 == 0)
                        p1_eq = 0;
                    else
                        p1_eq = -1*(p1)*log2(p1);
                    end
                    if (~s0)
                        p0 = 0;
                    else
                        p0 = ((s0 - s0_and_true) / s0);
                    end
                    if (p0 == 0)
                        p0_eq = 0;
                    else
                        p0_eq = -1*(p0)*log2(p0);
                    end
                    entropy_s0 = p1_eq + p0_eq;
                    
                    ganancia(i) = entropiaActual - ((s1/numeroEjemplos)*entropy_s1) - ((s0/numeroEjemplos)*entropy_s0);
                end
            end
            
            % Elige el atributo que maximiza las ganancias.
            [~, bestAttribute] = max(ganancia);
            
            % Establecemos arbol.valor a la cadena relevante de bestattribute
            arbol.valor = atributos{bestAttribute};
            
            % Retirar el atributo de división de atributosActivos
            atributosActivos(bestAttribute) = 0;
            
            % Inicialice y cree las nuevas matrices de ejemplo.
            ejemplos_0 = []; ejemplos_0_index = 1;
            ejemplos_1 = []; ejemplos_1_index = 1;
            for i=1:numeroEjemplos
                if (ejemplos(i, bestAttribute)) % Esta instancia lo tiene como 1/true
                    ejemplos_1(ejemplos_1_index, :) = ejemplos(i, :); % copiar
                    ejemplos_1_index = ejemplos_1_index + 1;
                else
                    ejemplos_0(ejemplos_0_index, :) = ejemplos(i, :);
                    ejemplos_0_index = ejemplos_0_index + 1;
                end
            end
            
            % Para ambos valores del atributo de división.
            % Para valor = falso o 0, corresponde a la rama izquierda
            % Si Ejemplos_0 está vacío, agregue nodo de hoja a la izquierda con la
            % etiqueta necesaria
            if (isempty(ejemplos_0))
                hoja = struct('valor', 'null', 'izquierdo', 'null', 'derecho', 'null');
                if (sumUltimaCol >= numeroEjemplos / 2) % para la matri ejemplos
                    hoja.valor = 'true';
                else
                    hoja.valor = 'false';
                end
                arbol.izquierdo = hoja;
            else
                % Aquí  usamos recursión .
                arbol.izquierdo = ID3(ejemplos_0, atributos, atributosActivos);
            end
            
            % Para valor = verdadero o 1, corresponde a la rama derecha
            % Si los ejemplos_1 está vacío, agregue un nodo de hoja a la derecha con la etiqueta relevante
            if (isempty(ejemplos_1))
                hoja = struct('valor', 'null', 'izquierdo', 'null', 'derecho', 'null');
                if (sumUltimaCol >= numeroEjemplos / 2) % para la matriz ejempos
                    hoja.valor = 'true';
                else
                    hoja.valor = 'false';
                end
                arbol.derecho = hoja;
            else
                % Aquí usamos recursion
                arbol.derecho = ID3(ejemplos_1, atributos, atributosActivos);
            end
            
            % retornamos el arbol
            return
                end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CargardatosButton
        function CargardatosButtonPushed(app, event)
            [name, path] = uigetfile('*.csv', 'Indica el archivo con los datos a procesar');
            if name ~= 0
                data = strcat(path, name);
                table = readtable(data);
                app.UITable.Data = table;
                app.UITable.ColumnName = table.Properties.VariableNames;
                app.ArchivoEditField.Value = name;
            end
        end

        % Button pushed function: GraficarButton
        function Graficar(app, event)
            p = [2 4 2 0 6 4 6];
            g = digraph(p(p~=0),find(p));
            plot(app.UIAxes, g,"ShowArrows",false)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ArbolesdedesicionLabel = uilabel(app.UIFigure);
            app.ArbolesdedesicionLabel.Text = 'Arboles de desicion';
            app.DatosPanel = uipanel(app.UIFigure);
            app.DatosPanel.Title = 'Datos';
            app.UITable = uitable(app.DatosPanel);
            app.UITable.ColumnName = '';
            app.UITable.RowName = {};
            app.CargardatosButton = uibutton(app.DatosPanel, 'push');
            app.CargardatosButton.ButtonPushedFcn = createCallbackFcn(app, @CargardatosButtonPushed, true);
            app.CargardatosButton.Text = 'Cargar datos';
            app.ArchivoEditFieldLabel = uilabel(app.DatosPanel);
            app.ArchivoEditFieldLabel.Text = 'Archivo';
            app.ArchivoEditField = uieditfield(app.DatosPanel, 'text');
            app.ArchivoEditField.Editable = 'off';
            app.GraficacionPanel = uipanel(app.UIFigure);
            app.GraficacionPanel.Title = 'Graficacion';
            app.UIAxes = uiaxes(app.GraficacionPanel);
            title(app.UIAxes, 'Arbol de desicion')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.GraficarButton = uibutton(app.GraficacionPanel, 'push');
            app.GraficarButton.ButtonPushedFcn = createCallbackFcn(app, @Graficar, true);
            app.GraficarButton.Text = 'Graficar';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = arboles_de_desicion

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end